classdef TDD_Processor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ProcessImagesTab                matlab.ui.container.Tab
        ProcessImagingExperimentDataLabel  matlab.ui.control.Label
        FrameSelect                     matlab.ui.control.NumericEditField
        FiletopreviewDropDown           matlab.ui.control.DropDown
        FiletopreviewDropDownLabel      matlab.ui.control.Label
        FrameSlider                     matlab.ui.control.Slider
        FrameSliderLabel                matlab.ui.control.Label
        RUNButton                       matlab.ui.control.Button
        BottomFiberIndexFileEditField   matlab.ui.control.EditField
        BottomFiberIndexFileEditFieldLabel  matlab.ui.control.Label
        SetBotIdx                       matlab.ui.control.Button
        TopFiberIndexFileEditField      matlab.ui.control.EditField
        TopFiberIndexFileEditFieldLabel  matlab.ui.control.Label
        SetTopIdx                       matlab.ui.control.Button
        SetProcessingFolder             matlab.ui.control.Button
        SamplesDirectoryEditField       matlab.ui.control.EditField
        SamplesDirectoryEditFieldLabel  matlab.ui.control.Label
        BackgroundOffsetImageEditField  matlab.ui.control.EditField
        BackgroundOffsetImageEditFieldLabel  matlab.ui.control.Label
        SetBackgroundBtn                matlab.ui.control.Button
        NormalizationImageEditField     matlab.ui.control.EditField
        NormalizationImageEditFieldLabel  matlab.ui.control.Label
        SetNormalizeBtn                 matlab.ui.control.Button
        ImageFormationModeDropDown      matlab.ui.control.DropDown
        ImageFormationModeDropDownLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        LiveDisplayTab                  matlab.ui.container.Tab
        ApplyBackgroundNormalizationButton  matlab.ui.control.Button
        ConnectCameraButton             matlab.ui.control.Button
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        AlignmentTab                    matlab.ui.container.Tab
        ConnectCameraButton_2           matlab.ui.control.Button
        UIAxes2_6                       matlab.ui.control.UIAxes
        UIAxes2_5                       matlab.ui.control.UIAxes
        UIAxes2_3                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        idxMap = struct('v',0,'h',0); % fiber index mapping
        sampleDirectory; % Folder of the imaging samples
        imageStack; % Raw image stack read by TiffRead
        sampleFiles; % Filelist of the samples
        backgroundArr; % background offset matrix
        normalizeArr; % normalize matrix
        baseSize = [20,20]; %Output image base size
        backgroundExists = false; %the background file exists
        normalizerExists = false; %normalization file exists
        botIdxExists = false; %bottom indexer exists
        topIdxExists = false; %top indexer exists
        sampleExists = false; %sample folder exists
        previewStack % Preview image stack
        elapsetime % Description
    end
    
    methods (Access = private)

        function [] = getNorm(app)
            %Get light normalization image if it has been selected
            nrmRaw = imread_big(app.NormalizationImageEditField.Value);
            nrmim = mean(remapImageTDD(nrmRaw, app.idxMap, [20,20], app.ImageFormationModeDropDown.Value),3) - app.backgroundArr;
            app.normalizeArr = getNormalization(double(nrmim),95,5);
        end
        
        function [] = getBackground(app)
            %Get dark background offset image if it has been selected
            bkgdRaw = imread_big(app.BackgroundOffsetImageEditField.Value);
            app.backgroundArr = mean(remapImageTDD(bkgdRaw, app.idxMap, [20,20], app.ImageFormationModeDropDown.Value),3);
            
        end


        function [] = writeLog(app,folderName)
            fhandle = fopen([folderName,'log.txt'],'w');
            fprintf(fhandle,'Processing Date: %s\n',datestr(datetime('today')));
            fprintf(fhandle,'Top Index Reference File: %s\n',app.TopFiberIndexFileEditField.Value);
            fprintf(fhandle,'Bottom Index Reference File: %s\n',app.BottomFiberIndexFileEditField.Value);
            fprintf(fhandle,'Background Offset Image: %s\n',app.BackgroundOffsetImageEditField.Value);
            fprintf(fhandle,'Normalization Bright Image: %s\n',app.NormalizationImageEditField.Value);
            fprintf(fhandle,'Image Formation Method: %s\n',app.ImageFormationModeDropDown.Value);
            fprintf(fhandle,'Processing Time (s): %0.4f\n',app.elapsetime);
            fprintf(fhandle,'App Version: 2.2\n');
            fclose(fhandle);            
        end
        
        function [] = refreshAll(app,reload)
            
            if app.sampleExists
                app.SamplesDirectoryEditField.BackgroundColor = [1,1,1];
            end         
            if app.botIdxExists
                app.BottomFiberIndexFileEditField.BackgroundColor = [1,1,1];
            end
            if app.topIdxExists
                app.TopFiberIndexFileEditField.BackgroundColor = [1,1,1];
            end

            if all([app.backgroundExists,app.topIdxExists,app.botIdxExists])
                app.SetNormalizeBtn.Enable = true;
                if reload
                    getBackground(app);
                end
            end
   
            if all([reload,app.normalizerExists,app.backgroundExists,app.topIdxExists,app.botIdxExists])
                getNorm(app);
            end       

            if all([app.topIdxExists,app.botIdxExists,app.sampleExists])
                app.RUNButton.Enable = true;
                app.FiletopreviewDropDown.Enable = 1; %Enable the preview dropdown if there is a target sample folder and a top and bot index
            else
                app.RUNButton.Enable = false;
            end
            
            if ~strcmpi(app.FiletopreviewDropDown.Value, 'None')
                f = {[app.sampleDirectory,'\',app.FiletopreviewDropDown.Value]};
                app.previewStack = processStack(f,app.idxMap,app.baseSize,app.backgroundArr,app.normalizeArr,[20,40],app.ImageFormationModeDropDown.Value);
                imshow(app.previewStack(:,:,round(app.FrameSlider.Value)),'Parent',app.UIAxes);
                drawnow
            end
            
            drawnow;
            figure(app.UIFigure)
        end
        
        function newFolderName = genBatchFolder(app,targetDir)
            i = 1;
            while(exist([targetDir,'\Batch',sprintf('%04d',i)],'dir'))
                i = i+1;
            end
            newFolderName = [targetDir,'\Batch',sprintf('%04d',i)];
            mkdir(newFolderName);
        end
        

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isfile([userpath,'\','TDD_Preferences.mat'])
                load([userpath,'\','TDD_Preferences.mat']);
                
                if topFiberFileExists
                    app.topIdxExists = true;
                    app.TopFiberIndexFileEditField.Value = topFiberFile;
                end
                
                if botFiberFileExists
                    app.botIdxExists = true;
                    app.BottomFiberIndexFileEditField.Value = botFiberFile;
                end
                
                if topFiberFileExists || botFiberFileExists
                    app.idxMap = idxMap;
                end
                
                if normalizerExists
                    app.normalizerExists = true;
                    app.normalizeArr = normalizerArr;
                    app.NormalizationImageEditField.Value = normalizerFile;
                end
                
                if backgroundExists
                    app.backgroundExists = true;
                    app.backgroundArr = backgroundArr;
                    app.BackgroundOffsetImageEditField.Value = backgroundFile;
                end 
                refreshAll(app,0);
 
            end
        end

        % Button pushed function: SetProcessingFolder
        function SetProcessingFolderButtonPushed(app, event)
            folder = uigetdir;
            drawnow;
            figure(app.UIFigure)
            if folder ~= 0
                app.SamplesDirectoryEditField.Value = folder;
                app.sampleDirectory = folder;
                
                fileList = dir([app.SamplesDirectoryEditField.Value,'\*.tif']);
                
                [~,idx] = sort([fileList(:).bytes],'descend');
                
                fileList = {fileList(idx).name};
                app.sampleFiles = fileList;
                
                app.sampleExists = true;
                app.FiletopreviewDropDown.Items = ['None',fileList];
                app.FiletopreviewDropDown.Value = 'None';     
                refreshAll(app,0);
            end
            
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: SetTopIdx
        function SetTopIdxButtonPushed(app, event)
            [file,folder] = uigetfile('*.mat');
            drawnow;
            figure(app.UIFigure)
            if file ~= 0
                app.TopFiberIndexFileEditField.Value = [folder,file];
                app.topIdxExists = true;
                app.idxMap(1) = formIdxMap(importdata(app.TopFiberIndexFileEditField.Value),[0,0],4);
                refreshAll(app,1);
            end
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: SetBotIdx
        function SetBotIdxButtonPushed(app, event)
            [file,folder] = uigetfile('*.mat');
            drawnow;
            figure(app.UIFigure)
            if file ~= 0
                app.BottomFiberIndexFileEditField.Value = [folder,file];
                app.botIdxExists = true;
                app.idxMap(2) = formIdxMap(importdata(app.BottomFiberIndexFileEditField.Value),[0,4],4);
                refreshAll(app,1);
            end

            drawnow;
            figure(app.UIFigure)        
        end

        % Button pushed function: SetNormalizeBtn
        function SetNormalizeBtnButtonPushed(app, event)
            [file,folder] = uigetfile('*.tif');
            drawnow;
            figure(app.UIFigure)
            if file ~= 0
                app.NormalizationImageEditField.Value = [folder,file];
                app.normalizerExists = true;
                refreshAll(app,1);
            end
           
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: SetBackgroundBtn
        function SetBackgroundBtnButtonPushed(app, event)
            [file,folder] = uigetfile('*.tif');
            drawnow;
            figure(app.UIFigure)
            if file ~= 0
                app.BackgroundOffsetImageEditField.Value = [folder,file];
                app.backgroundExists = true;
                refreshAll(app,1);
            end
            drawnow;
            figure(app.UIFigure)
        end

        % Value changed function: FiletopreviewDropDown
        function FiletopreviewDropDownValueChanged(app, event)
            if ~strcmpi(app.FiletopreviewDropDown.Value, 'None')
                f = {[app.sampleDirectory,'\',app.FiletopreviewDropDown.Value]};
                app.previewStack = processStack(f,app.idxMap,app.baseSize,app.backgroundArr,app.normalizeArr,[20,40],app.ImageFormationModeDropDown.Value);
                app.FrameSlider.Enable = true;
                app.FrameSlider.Limits = [1,size(app.previewStack,3)];
                app.FrameSelect.Enable = true;
                imshow(app.previewStack(:,:,round(app.FrameSlider.Value)),'Parent',app.UIAxes);
                drawnow
            else
                app.previewStack = 0;
                app.FrameSlider.Enable = false;
                app.FrameSelect.Enable = false;
            end
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            %Process all images within a folder
            tic
            batchFolder = genBatchFolder(app,app.sampleDirectory);
            app.RUNButton.Enable = false;
            
            sdir = app.sampleDirectory;
            tsampleFiles = app.sampleFiles;
            iMap = app.idxMap;
            tbaseSize = app.baseSize;
            bkgd = app.backgroundArr;
            nrm = app.normalizeArr;
            mode = app.ImageFormationModeDropDown.Value;
            totalFiles = length(app.sampleFiles);
            
            WaitMessage = parfor_wait(totalFiles, 'Waitbar', true);
            
            parfor i = 1:totalFiles
                f = tsampleFiles{i};
                
                ftmp = {[sdir,'\',f]};
                
                imageExport = processStack(ftmp,iMap,tbaseSize,bkgd,nrm,[20,40],mode);
                writeTiff(f,[batchFolder,'\'],imageExport);
                
                WaitMessage.Send;
                %waitbar(i/totalFiles,h);
            end
            
            app.elapsetime = toc;
            writeLog(app,[batchFolder,'\']);
            
            WaitMessage.Destroy

            app.elapsetime = 0;
            app.RUNButton.Enable = true;
        end

        % Value changed function: ImageFormationModeDropDown
        function ImageFormationModeDropDownValueChanged(app, event)
            value = app.ImageFormationModeDropDown.Value;
            refreshAll(app,1);
        end

        % Value changed function: FrameSelect
        function FrameSelectValueChanged(app, event)
            value = round(app.FrameSelect.Value);
            imshow(app.previewStack(:,:,value),'Parent',app.UIAxes);
            drawnow
            app.FrameSlider.Value = value;
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            changingValue = event.Value;
            value = round(changingValue);
            imshow(app.previewStack(:,:,value),'Parent',app.UIAxes);
            drawnow
            app.FrameSelect.Value = value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            preferences.topFiberFileExists = app.topIdxExists;
            preferences.botFiberFileExists = app.botIdxExists;
            preferences.normalizerExists = app.normalizerExists;
            preferences.backgroundExists = app.backgroundExists;
            
            preferences.botFiberFile = 'None';
            preferences.topFiberFile = 'None';
            preferences.normalizerFile = 'None';
            preferences.backgroundFile = 'None';
            preferences.backgroundArr = 0;
            preferences.normalizerArr = 0;
            preferences.idxMap = 0;
            
            
            if app.topIdxExists
                preferences.topFiberFile = app.TopFiberIndexFileEditField.Value;
            end
            
            if app.botIdxExists
                preferences.botFiberFile = app.BottomFiberIndexFileEditField.Value;
            end
            
            if app.topIdxExists || app.botIdxExists
                preferences.idxMap = app.idxMap;
            end
            
            if app.normalizerExists
                preferences.normalizerFile = app.NormalizationImageEditField.Value;
                preferences.normalizerArr = app.normalizeArr;
            end
            
            if app.backgroundExists
                preferences.backgroundFile = app.BackgroundOffsetImageEditField.Value;
                preferences.backgroundArr = app.backgroundArr;
            end
            
            save([userpath,'\','TDD_Preferences.mat'],'-struct','preferences');
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ProcessImagesTab = uitab(app.TabGroup);
            app.ProcessImagesTab.Title = 'Process Images';
            app.UIAxes = uiaxes(app.ProcessImagesTab);
            title(app.UIAxes, 'Preview')
            app.ImageFormationModeDropDownLabel = uilabel(app.ProcessImagesTab);
            app.ImageFormationModeDropDownLabel.Text = 'Image Formation Mode';
            app.ImageFormationModeDropDown = uidropdown(app.ProcessImagesTab);
            app.ImageFormationModeDropDown.Items = {'4x4 Sum', '4x4 Tile', '2x2 Tile'};
            app.ImageFormationModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageFormationModeDropDownValueChanged, true);
            app.ImageFormationModeDropDown.Tooltip = {'Select how the image is formed from each 4x4 fiber pixel group.'; '4x4 Sum: Default. Adds together all pixels within the fiber region.'; '4x4 Tile: Uses the direct 4x4 pixel tile and inserts it into the image'; '2x2 Tile:divides fibers into 4 quadrants, each of which is summed.'};
            app.ImageFormationModeDropDown.Value = '4x4 Sum';
            app.SetNormalizeBtn = uibutton(app.ProcessImagesTab, 'push');
            app.SetNormalizeBtn.ButtonPushedFcn = createCallbackFcn(app, @SetNormalizeBtnButtonPushed, true);
            app.SetNormalizeBtn.Enable = 'off';
            app.SetNormalizeBtn.Text = 'Select File';
            app.NormalizationImageEditFieldLabel = uilabel(app.ProcessImagesTab);
            app.NormalizationImageEditFieldLabel.Text = 'Normalization Image';
            app.NormalizationImageEditField = uieditfield(app.ProcessImagesTab, 'text');
            app.NormalizationImageEditField.Editable = 'off';
            app.NormalizationImageEditField.Tooltip = {'Select the uniform bright image to normalize the fiber intensities'};
            app.NormalizationImageEditField.Value = 'None';
            app.SetBackgroundBtn = uibutton(app.ProcessImagesTab, 'push');
            app.SetBackgroundBtn.ButtonPushedFcn = createCallbackFcn(app, @SetBackgroundBtnButtonPushed, true);
            app.SetBackgroundBtn.Text = 'Select File';
            app.BackgroundOffsetImageEditFieldLabel = uilabel(app.ProcessImagesTab);
            app.BackgroundOffsetImageEditFieldLabel.Text = 'Background Offset Image';
            app.BackgroundOffsetImageEditField = uieditfield(app.ProcessImagesTab, 'text');
            app.BackgroundOffsetImageEditField.Editable = 'off';
            app.BackgroundOffsetImageEditField.Tooltip = {'Select the dark image to get the baseline signal of the system for offset subtraction'};
            app.BackgroundOffsetImageEditField.Value = 'None';
            app.SamplesDirectoryEditFieldLabel = uilabel(app.ProcessImagesTab);
            app.SamplesDirectoryEditFieldLabel.Text = 'Samples Directory';
            app.SamplesDirectoryEditField = uieditfield(app.ProcessImagesTab, 'text');
            app.SamplesDirectoryEditField.Editable = 'off';
            app.SamplesDirectoryEditField.Tooltip = {'Select the folder holding samples for processing'};
            app.SamplesDirectoryEditField.Value = 'None';
            app.SetProcessingFolder = uibutton(app.ProcessImagesTab, 'push');
            app.SetProcessingFolder.ButtonPushedFcn = createCallbackFcn(app, @SetProcessingFolderButtonPushed, true);
            app.SetProcessingFolder.Text = 'Select Folder';
            app.SetTopIdx = uibutton(app.ProcessImagesTab, 'push');
            app.SetTopIdx.ButtonPushedFcn = createCallbackFcn(app, @SetTopIdxButtonPushed, true);
            app.SetTopIdx.Text = 'Select File';
            app.TopFiberIndexFileEditFieldLabel = uilabel(app.ProcessImagesTab);
            app.TopFiberIndexFileEditFieldLabel.Text = 'Top Fiber Index File';
            app.TopFiberIndexFileEditField = uieditfield(app.ProcessImagesTab, 'text');
            app.TopFiberIndexFileEditField.Editable = 'off';
            app.TopFiberIndexFileEditField.Tooltip = {'Select the mat file containing the top fiber 2D index mapping'};
            app.TopFiberIndexFileEditField.Value = 'None';
            app.SetBotIdx = uibutton(app.ProcessImagesTab, 'push');
            app.SetBotIdx.ButtonPushedFcn = createCallbackFcn(app, @SetBotIdxButtonPushed, true);
            app.SetBotIdx.Text = 'Select File';
            app.BottomFiberIndexFileEditFieldLabel = uilabel(app.ProcessImagesTab);
            app.BottomFiberIndexFileEditFieldLabel.Text = 'Bottom Fiber Index File';
            app.BottomFiberIndexFileEditField = uieditfield(app.ProcessImagesTab, 'text');
            app.BottomFiberIndexFileEditField.Editable = 'off';
            app.BottomFiberIndexFileEditField.Tooltip = {'Select the mat file containing the bottom fiber 2D index mapping'};
            app.BottomFiberIndexFileEditField.Value = 'None';
            app.RUNButton = uibutton(app.ProcessImagesTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Enable = 'off';
            app.RUNButton.Text = 'RUN';
            app.FrameSliderLabel = uilabel(app.ProcessImagesTab);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.ProcessImagesTab);
            app.FrameSlider.Limits = [1 100];
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSlider.Enable = 'off';
            app.FrameSlider.Value = 1;
            app.FiletopreviewDropDownLabel = uilabel(app.ProcessImagesTab);
            app.FiletopreviewDropDownLabel.Text = 'File to preview';
            app.FiletopreviewDropDown = uidropdown(app.ProcessImagesTab);
            app.FiletopreviewDropDown.Items = {'None', ''};
            app.FiletopreviewDropDown.ValueChangedFcn = createCallbackFcn(app, @FiletopreviewDropDownValueChanged, true);
            app.FiletopreviewDropDown.Enable = 'off';
            app.FiletopreviewDropDown.Value = 'None';
            app.FrameSelect = uieditfield(app.ProcessImagesTab, 'numeric');
            app.FrameSelect.Limits = [1 Inf];
            app.FrameSelect.ValueChangedFcn = createCallbackFcn(app, @FrameSelectValueChanged, true);
            app.FrameSelect.Enable = 'off';
            app.FrameSelect.Value = 1;
            app.ProcessImagingExperimentDataLabel = uilabel(app.ProcessImagesTab);
            app.ProcessImagingExperimentDataLabel.Text = 'Process Imaging Experiment Data';
            app.LiveDisplayTab = uitab(app.TabGroup);
            app.LiveDisplayTab.Title = 'Live Display';
            app.UIAxes2 = uiaxes(app.LiveDisplayTab);
            title(app.UIAxes2, 'Histogram')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.LiveDisplayTab);
            title(app.UIAxes2_2, 'Live display')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.ConnectCameraButton = uibutton(app.LiveDisplayTab, 'push');
            app.ConnectCameraButton.Text = 'Connect Camera';
            app.ApplyBackgroundNormalizationButton = uibutton(app.LiveDisplayTab, 'push');
            app.ApplyBackgroundNormalizationButton.Text = {'Apply Background'; '& Normalization'};
            app.AlignmentTab = uitab(app.TabGroup);
            app.AlignmentTab.Title = 'Alignment';
            app.UIAxes2_3 = uiaxes(app.AlignmentTab);
            title(app.UIAxes2_3, 'Left')
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes2_5 = uiaxes(app.AlignmentTab);
            title(app.UIAxes2_5, 'Raw')
            xlabel(app.UIAxes2_5, 'X')
            ylabel(app.UIAxes2_5, 'Y')
            zlabel(app.UIAxes2_5, 'Z')
            app.UIAxes2_6 = uiaxes(app.AlignmentTab);
            title(app.UIAxes2_6, 'Right')
            xlabel(app.UIAxes2_6, 'X')
            ylabel(app.UIAxes2_6, 'Y')
            zlabel(app.UIAxes2_6, 'Z')
            app.ConnectCameraButton_2 = uibutton(app.AlignmentTab, 'push');
            app.ConnectCameraButton_2.Text = 'Connect Camera';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TDD_Processor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end